classdef LabelData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        AddlabelLabel     matlab.ui.control.Label
        FinishButton      matlab.ui.control.Button
        TremorButton      matlab.ui.control.Button
        MovementButton    matlab.ui.control.Button
        MotionlessButton  matlab.ui.control.Button
        File1Label        matlab.ui.control.Label
        FilesLabel        matlab.ui.control.Label
        UITable           matlab.ui.control.Table
        SelectButton      matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Description
        ROI % Description
    end
    
    methods (Access = private)

        function loadAccelerometerData(app)
            % Load the data.
            data = get_acc_data("All");
            sample = data{6};
            
            % Store the data in a table and display it.
            app.Data = table(sample);
            app.UITable.Data = app.Data;
            
            % Update the axes with the corresponding data.
            plot(app.UIAxes, sample.timestamp, sample.x, 'black');
        end
        
%         function allevents(app, src, event)
%             evname = event.EventName;
%             switch(evname)
%                 case{'MovingROI'}
%                     disp(['ROI moving previous position: ' mat2str(event.PreviousPosition)]);
%                     disp(['ROI moving current position: ' mat2str(event.CurrentPosition)]);
%                 case{'ROIMoved'}
%                     disp(['ROI moved previous position: ' mat2str(event.PreviousPosition)]);
%                     disp(['ROI moved current position: ' mat2str(event.CurrentPosition)]);
%             end
%             
%             app.ROICoordinates = event.CurrentPosition;
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            loadAccelerometerData(app);
        end

        % Callback function
        function AddrectButtonPushed(app, event)
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            for i = 1:length(app.ROI)
                disp(app.ROI(i).Position);
            end
        end

        % Button pushed function: TremorButton
        function TremorButtonPushed(app, event)
            app.ROI = [app.ROI, drawrectangle(app.UIAxes, 'Color', 'r', 'Position', [1,-1,5,2])];
            
            %addlistener(app.ROI(end),'MovingROI',@app.allevents);
            %addlistener(app.ROI(end),'ROIMoved',@app.allevents);
        end

        % Button pushed function: MovementButton
        function MovementButtonPushed(app, event)
            app.ROI = [app.ROI, drawrectangle(app.UIAxes, 'Color', 'b', 'Position', [1,-1,5,2])];
        end

        % Button pushed function: MotionlessButton
        function MotionlessButtonPushed(app, event)
            app.ROI = [app.ROI, drawrectangle(app.UIAxes, 'Color', 'g', 'Position', [1,-1,5,2])];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddlabelLabel = uilabel(app.UIFigure);
            app.AddlabelLabel.Text = 'Add label';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.TremorButton = uibutton(app.UIFigure, 'push');
            app.TremorButton.ButtonPushedFcn = createCallbackFcn(app, @TremorButtonPushed, true);
            app.TremorButton.Text = 'Tremor';
            app.MovementButton = uibutton(app.UIFigure, 'push');
            app.MovementButton.ButtonPushedFcn = createCallbackFcn(app, @MovementButtonPushed, true);
            app.MovementButton.Text = 'Movement';
            app.MotionlessButton = uibutton(app.UIFigure, 'push');
            app.MotionlessButton.ButtonPushedFcn = createCallbackFcn(app, @MotionlessButtonPushed, true);
            app.MotionlessButton.Text = 'Motionless';
            app.File1Label = uilabel(app.UIFigure);
            app.File1Label.Text = 'File 1';
            app.FilesLabel = uilabel(app.UIFigure);
            app.FilesLabel.Text = 'Files';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Filename'};
            app.UITable.RowName = {};
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'X-axis')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabelData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end