classdef tremorLabeling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PHTLabelAppUIFigure        matlab.ui.Figure
        SyncdataButton             matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        FilesTab                   matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        Labeleddata00measurements  matlab.ui.control.Label
        LabelingTab                matlab.ui.container.Tab
        AddchunkButton             matlab.ui.control.Button
        RemovechunkButton          matlab.ui.control.Button
        PHTButton                  matlab.ui.control.Button
        MovementButton             matlab.ui.control.Button
        StaticButton               matlab.ui.control.Button
        ClassifychunkLabel         matlab.ui.control.Label
        TabGroup2                  matlab.ui.container.TabGroup
        XaxisTab                   matlab.ui.container.Tab
        UIAxesAccX                 matlab.ui.control.UIAxes
        YaxisTab                   matlab.ui.container.Tab
        UIAxesAccY                 matlab.ui.control.UIAxes
        ZaxisTab                   matlab.ui.container.Tab
        UIAxesAccZ                 matlab.ui.control.UIAxes
        AccelerometerFFTTab        matlab.ui.container.Tab
        UIAxesAccFFT               matlab.ui.control.UIAxes
        TabGroup2_2                matlab.ui.container.TabGroup
        RectpointsFFTTab           matlab.ui.container.Tab
        UIAxesCameraPointsFFT      matlab.ui.control.UIAxes
        RectsquareFFTTab           matlab.ui.container.Tab
        UIAxesCameraSquareFFT      matlab.ui.control.UIAxes
        AudioFFTTab                matlab.ui.container.Tab
        UIAxesAudioFFT             matlab.ui.control.UIAxes
        PlayButton                 matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        secLabel                   matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        UIAxesVideo                matlab.ui.control.UIAxes
        SavefileButton             matlab.ui.control.Button
        SelectedmeasurementLabel   matlab.ui.control.Label
        emptyLabel                 matlab.ui.control.Label
        Message                    matlab.ui.control.Label
        UpdateallfilesButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        measurements % All measurements
        measurement % Selected measurements
        ROI % ROI for labeling
        selectedMeasurementIndex % Description
        interruptVideo
    end
    
    methods (Access = private)
        
        function saveChunk(app, chunk)
            [accData, audioData, cameraData] = getDataFromChunk(chunk.startTime, chunk.finishTime, app.measurement);
            
            chunk.accData = accData;
            chunk.audioData = audioData;
            chunk.cameraData = cameraData;
            
            app.measurement.chunks{end+1} = chunk;
            app.measurement.isLabeled = 'true';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            allMeasurements = sync_measurement_data("Research");
            app.measurements = {};
            
            for i=1:length(allMeasurements)
                if isfield(allMeasurements{i}, 'accData') && isfield(allMeasurements{i}, 'audioData') && isfield(allMeasurements{i}, 'cameraData') && isfield(allMeasurements{i}, 'videoData')
                    app.measurements{end+1} = allMeasurements{i};
                end
            end
            
            N = length(app.measurements);
            
            data = {N};
            labeledCount = 0;
            
            for i=1:N
                data{i, 1} = app.measurements{i}.fileName;
                data{i, 2} = app.measurements{i}.isLabeled;
                
                if strcmp(app.measurements{i}.isLabeled, 'true')
                    labeledCount = labeledCount + 1;
                end
            end
          
            app.UITable.Data = data;
            
            [row, column] = find(strcmp(data, 'true'));
            
            s1 = uistyle;
            s1.BackgroundColor = 'red';
            s1.FontColor = 'white';
            addStyle(app.UITable, s1,'column', 2);
            
            s2 = uistyle;
            s2.BackgroundColor = 'green';
            s2.FontColor = 'black';
            addStyle(app.UITable, s2,'cell', [row, column]);
            
            disableDefaultInteractivity(app.UIAxes);
            str = sprintf('Labeled data: %d / %d measurements', labeledCount, N);
            app.Labeleddata00measurements.Text = str;
        end

        % Button pushed function: SyncdataButton
        function SyncdataButtonPushed(app, event)
            allMeasurements = sync_measurement_data("Research");
            app.measurements = {};
            
            for i=1:length(allMeasurements)
                if isfield(allMeasurements{i}, 'accData') && isfield(allMeasurements{i}, 'audioData') && isfield(allMeasurements{i}, 'cameraData') && isfield(allMeasurements{i}, 'videoData')
                    app.measurements{end+1} = allMeasurements{i};
                end
            end
            
            N = length(app.measurements);
            
            data = {N};
            labeledCount = 0;
            
            for i=1:N
                data{i, 1} = app.measurements{i}.fileName;
                data{i, 2} = app.measurements{i}.isLabeled;
                
                if strcmp(app.measurements{i}.isLabeled, 'true')
                    labeledCount = labeledCount + 1;
                end
            end
            
            app.UITable.Data = data;
            
            [row, column] = find(strcmp(data, 'true'));
            
            s1 = uistyle;
            s1.BackgroundColor = 'red';
            s1.FontColor = 'white';
            addStyle(app.UITable, s1,'column', 2);
            
            s2 = uistyle;
            s2.BackgroundColor = 'green';
            s2.FontColor = 'black';
            addStyle(app.UITable, s2,'cell', [row, column]);
            
            str = sprintf('Labeled data: %d / %d measurements', labeledCount, N);
            app.Labeleddata00measurements.Text = str;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.Message.Text = '';
            
            app.selectedMeasurementIndex = event.Indices(1);
            app.measurement = app.measurements{event.Indices(1)};
            app.emptyLabel.Text = app.measurement.fileName;
            
            audioTime = (size(app.measurement.audioData.y{1}) / app.measurement.audioData.fs{1}(1));
             
            plot(app.UIAxes, [0 audioTime(1)], [0 0], 'color', 'black', 'linewidth', 1);
            app.UIAxes.XTick = 0:1:audioTime(1);
            
            plot(app.UIAxesAccX, app.measurement.accData.timestamp, app.measurement.accData.x, 'red');
            plot(app.UIAxesAccY, app.measurement.accData.timestamp, app.measurement.accData.y, 'red');
            plot(app.UIAxesAccZ, app.measurement.accData.timestamp, app.measurement.accData.z, 'red');
            
            app.UIAxesAccX.XTick = 0:1:app.measurement.accData.timestamp(end);
            app.UIAxesAccY.XTick = 0:1:app.measurement.accData.timestamp(end);
            app.UIAxesAccZ.XTick = 0:1:app.measurement.accData.timestamp(end);
            
            app.ROI = {};
            
            if isfield(app.measurement, 'chunks')
                for i = 1:length(app.measurement.chunks)
                    chunk = app.measurement.chunks{i};             
                    color = 'green';
                    
                    if chunk.classification == 1
                        color = 'red';
                    elseif chunk.classification == 2
                        color = 'blue'; 
                    else
                        color = 'green'; 
                    end
                    
                    app.ROI{size(app.ROI, 2)+1} = images.roi.Rectangle;
                    app.ROI{end} = [app.ROI{end}, drawrectangle(app.UIAxes, 'Color', color, 'Position', [chunk.startTime, -1, chunk.finishTime - chunk.startTime, 2])];
                end
            end
            
            % Accelerometer FFT
            
            timewindow_size = 2; % 2 seconds window
            data_features = extractAccFeatures(app.measurement.accData, timewindow_size);
            t = floor(app.measurement.accData.timestamp(end));
            
            [fft_X_x, fft_X_y, fft_Y_x, fft_Y_y, fft_Z_x, fft_Z_y] = get_acc_max_fft(data_features, t);
            
            cla(app.UIAxesAccFFT); 
            hold(app.UIAxesAccFFT, "off");
            hold(app.UIAxesAccFFT, "on");
            
            plot(app.UIAxesAccFFT, fft_X_x, fft_X_y, 'r');
            plot(app.UIAxesAccFFT, fft_Y_x, fft_Y_y, 'g');
            plot(app.UIAxesAccFFT, fft_Z_x, fft_Z_y, 'b');
            
            legend(app.UIAxesAccFFT, 'X-axis', 'Y-axis', 'Z-axis');
            app.UIAxesAccFFT.XTick = 0:1:t;
                        
            % Camera FFT
            
            sample = app.measurement.cameraData;
            processed_px1 = raw_camera_data_preprocessing(sample.px1); 
            processed_py1 = raw_camera_data_preprocessing(sample.py1); 
            
            processed_px2 = raw_camera_data_preprocessing(sample.px2); 
            processed_py2 = raw_camera_data_preprocessing(sample.py2); 
            
            processed_px3 = raw_camera_data_preprocessing(sample.px3); 
            processed_py3 = raw_camera_data_preprocessing(sample.py3); 
            
            processed_px4 = raw_camera_data_preprocessing(sample.px4); 
            processed_py4 = raw_camera_data_preprocessing(sample.py4); 
            
            timestamp = sample.timestamp;
            px1 = processed_px1';
            py1 = processed_py1';
            px2 = processed_px2';
            py2 = processed_py2';
            px3 = processed_px3';
            py3 = processed_py3';
            px4 = processed_px4';
            py4 = processed_py4';
            
            processed_sample = table(timestamp, px1, py1, px2, py2, px3, py3, px4, py4);
            
            timewindow_size = 2; % 2 seconds window
            cam_data_features = extract_camera_features(processed_sample, timewindow_size);
            
            % Plot camera features

            t = floor(timewindow_size * floor(processed_sample.timestamp(end) / 60));
            
            % Point 1
            
            fft_p1X_x = (1:t);
            fft_p1X_y = (1:t);
            
            for i = 1:t/2
                amp_max_x = max(cam_data_features{1, i});
                j = i * 2;
                fft_p1X_y(j) = amp_max_x;
                fft_p1X_y(j - 1) = amp_max_x;
            end
            
            fft_p1Y_y = (1:t);
            
            for i = 1:t/2
                amp_max_x = max(cam_data_features{5, i});
                j = i * 2;
                fft_p1Y_y(j) = amp_max_x;
                fft_p1Y_y(j - 1) = amp_max_x;
            end
            
            % Point 2
            
            fft_p2X_y = (1:t);
            
            for i = 1:t/2
                amp_max_x = max(cam_data_features{2, i});
                j = i * 2;
                fft_p2X_y(j) = amp_max_x;
                fft_p2X_y(j - 1) = amp_max_x;
            end

            fft_p2Y_y = (1:t);
            
            for i = 1:t/2
                amp_max_x = max(cam_data_features{6, i});
                j = i * 2;
                fft_p2Y_y(j) = amp_max_x;
                fft_p2Y_y(j - 1) = amp_max_x;
            end
            
            % Point 3
            
            fft_p3X_y = (1:t);
            
            for i = 1:t/2
                amp_max_x = max(cam_data_features{3, i});
                j = i * 2;
                fft_p3X_y(j) = amp_max_x;
                fft_p3X_y(j - 1) = amp_max_x;
            end
            
            fft_p3Y_y = (1:t);
            
            for i = 1:t/2
                amp_max_x = max(cam_data_features{7, i});
                j = i * 2;
                fft_p3Y_y(j) = amp_max_x;
                fft_p3Y_y(j - 1) = amp_max_x;
            end
            
            % Point 4
            
            fft_p4X_y = (1:t);
            
            for i = 1:t/2
                amp_max_x = max(cam_data_features{4, i});
                j = i * 2;
                fft_p4X_y(j) = amp_max_x;
                fft_p4X_y(j - 1) = amp_max_x;
            end
            
            fft_p4Y_y = (1:t);
            
            for i = 1:t/2
                amp_max_x = max(cam_data_features{8, i});
                j = i * 2;
                fft_p4Y_y(j) = amp_max_x;
                fft_p4Y_y(j - 1) = amp_max_x;
            end
           
            % Max FFT
            
            max_fft_x = fft_p1X_x;
            max_fft_y = (1:t);
            
            for i = 1:size(fft_p1X_y, 2)
                max_fft_y(i) = max([fft_p1X_y(i), fft_p2X_y(i), fft_p3X_y(i), fft_p4X_y(i)]);
            end
            
            plot(app.UIAxesCameraPointsFFT, max_fft_x, max_fft_y, 'r');
            app.UIAxesCameraPointsFFT.XTick = 0:2:t;
            
            % Square
            
            timewindow_size = 2; % 2 seconds window
            t = app.measurement.cameraData.timestamp;
            
            camera_square_features = extract_camera_square_features(app.measurement.cameraData, timewindow_size);
            [fft_x, fft_y] = get_camera_square_max_fft(camera_square_features, t(end) / 30);
            
            plot(app.UIAxesCameraSquareFFT, fft_x, fft_y, 'r');
            app.UIAxesCameraSquareFFT.XTick = 0:2:t(end);
            
            % Audio FFT
            
            y = app.measurement.audioData.y{1};
            fs = app.measurement.audioData.fs{1};
            
            timewindow = 2; % 2 seconds

            signal = y';
            audio_data_features = extract_audio_features(signal, fs, timewindow);
            [fft_x, fft_y] = get_audio_min_fft(audio_data_features, audioTime(1));
            plot(app.UIAxesAudioFFT, fft_x, fft_y, 'Color', [1, 0, 0]);
            app.UIAxesAudioFFT.XTick = 0:2:audioTime(1);
        end

        % Button pushed function: AddchunkButton
        function AddchunkButtonPushed(app, event)
            app.ROI{size(app.ROI, 2)+1} = images.roi.Rectangle;
            app.ROI{end} = [app.ROI{end}, drawrectangle(app.UIAxes, 'Color', 'green', 'Position', [0, -1, 5, 2])];
        end

        % Button pushed function: PHTButton
        function PHTButtonPushed(app, event)
            app.ROI{end}(2).Color = 'red';
        end

        % Button pushed function: MovementButton
        function MovementButtonPushed(app, event)
            app.ROI{end}(2).Color = 'blue';
        end

        % Button pushed function: StaticButton
        function StaticButtonPushed(app, event)
            app.ROI{end}(2).Color = 'green';
        end

        % Button pushed function: RemovechunkButton
        function RemovechunkButtonPushed(app, event)
            delete(app.ROI{end});
            app.ROI(end) = [];
        end

        % Button pushed function: SavefileButton
        function SavefileButtonPushed(app, event)
            app.measurement.chunks = {};
            app.measurement.isLabeled = 'false';
            
            for i = 1:size(app.ROI, 2)
                chunk = struct;
                chunk.fileName = app.measurement.fileName;
                chunk.startTime = app.ROI{i}(2).Position(1);
                chunk.finishTime = app.ROI{i}(2).Position(1) + app.ROI{i}(2).Position(3);
                
                if app.ROI{i}(2).Color(1) == 1
                    chunk.classification = 1;
                elseif app.ROI{i}(2).Color(2) == 1
                    chunk.classification = 3;
                else
                    chunk.classification = 2;
                end

                saveChunk(app, chunk);
            end
            
            app.measurements{app.selectedMeasurementIndex} = app.measurement;
            app.Message.Text = 'File has been saved';
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            v = VideoReader(app.measurement.videoData.Name);
            v.currentTime = 0;
            
            while v.hasFrame()
                if app.interruptVideo == 1 
                    app.interruptVideo = 0;
                    break;
                end
                
                temp = v.readFrame();
                rotImg = rot90(temp, 3);
                imshow(rotImg, 'Parent', app.UIAxesVideo);
                pause(1.0 / v.FrameRate);
                app.secLabel.Text = sprintf('%.f/%.f sec', v.currentTime, v.Duration);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.interruptVideo = 1;
        end

        % Button pushed function: UpdateallfilesButton
        function UpdateallfilesButtonPushed(app, event)
            measurements = app.measurements;
            save('/Users/denissvichkarev/Projects/TremorDetection/MATLAB/measurements.mat', 'measurements');
            app.Message.Text = 'All files have been updated';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PHTLabelAppUIFigure = uifigure('Visible', 'off');
            app.PHTLabelAppUIFigure.Name = 'PHT Label App';
            app.SyncdataButton = uibutton(app.PHTLabelAppUIFigure, 'push');
            app.SyncdataButton.ButtonPushedFcn = createCallbackFcn(app, @SyncdataButtonPushed, true);
            app.SyncdataButton.Text = 'Sync data';
            app.TabGroup = uitabgroup(app.PHTLabelAppUIFigure);
            app.FilesTab = uitab(app.TabGroup);
            app.FilesTab.Title = 'Files';
            app.UITable = uitable(app.FilesTab);
            app.UITable.ColumnName = {'Filename'; 'Is labeled'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Labeleddata00measurements = uilabel(app.FilesTab);
            app.Labeleddata00measurements.Text = 'Labeled data: 0 / 0 measurements';
            app.LabelingTab = uitab(app.TabGroup);
            app.LabelingTab.Title = 'Labeling';
            app.AddchunkButton = uibutton(app.LabelingTab, 'push');
            app.AddchunkButton.ButtonPushedFcn = createCallbackFcn(app, @AddchunkButtonPushed, true);
            app.AddchunkButton.Text = 'Add chunk';
            app.RemovechunkButton = uibutton(app.LabelingTab, 'push');
            app.RemovechunkButton.ButtonPushedFcn = createCallbackFcn(app, @RemovechunkButtonPushed, true);
            app.RemovechunkButton.Text = 'Remove chunk';
            app.PHTButton = uibutton(app.LabelingTab, 'push');
            app.PHTButton.ButtonPushedFcn = createCallbackFcn(app, @PHTButtonPushed, true);
            app.PHTButton.Text = 'PHT';
            app.MovementButton = uibutton(app.LabelingTab, 'push');
            app.MovementButton.ButtonPushedFcn = createCallbackFcn(app, @MovementButtonPushed, true);
            app.MovementButton.Text = 'Movement';
            app.StaticButton = uibutton(app.LabelingTab, 'push');
            app.StaticButton.ButtonPushedFcn = createCallbackFcn(app, @StaticButtonPushed, true);
            app.StaticButton.Text = 'Static';
            app.ClassifychunkLabel = uilabel(app.LabelingTab);
            app.ClassifychunkLabel.Text = 'Classify chunk:';
            app.TabGroup2 = uitabgroup(app.LabelingTab);
            app.XaxisTab = uitab(app.TabGroup2);
            app.XaxisTab.Title = 'X-axis';
            app.UIAxesAccX = uiaxes(app.XaxisTab);
            title(app.UIAxesAccX, 'Accelerometer X-axis')
            xlabel(app.UIAxesAccX, 'X')
            ylabel(app.UIAxesAccX, 'Y')
            zlabel(app.UIAxesAccX, 'Z')
            app.YaxisTab = uitab(app.TabGroup2);
            app.YaxisTab.Title = 'Y-axis';
            app.UIAxesAccY = uiaxes(app.YaxisTab);
            title(app.UIAxesAccY, 'Accelerometer Y-axis')
            xlabel(app.UIAxesAccY, 'X')
            ylabel(app.UIAxesAccY, 'Y')
            zlabel(app.UIAxesAccY, 'Z')
            app.ZaxisTab = uitab(app.TabGroup2);
            app.ZaxisTab.Title = 'Z-axis';
            app.UIAxesAccZ = uiaxes(app.ZaxisTab);
            title(app.UIAxesAccZ, 'Accelerometer Z-axis')
            xlabel(app.UIAxesAccZ, 'X')
            ylabel(app.UIAxesAccZ, 'Y')
            zlabel(app.UIAxesAccZ, 'Z')
            app.AccelerometerFFTTab = uitab(app.TabGroup2);
            app.AccelerometerFFTTab.Title = 'Accelerometer FFT';
            app.UIAxesAccFFT = uiaxes(app.AccelerometerFFTTab);
            title(app.UIAxesAccFFT, 'Accelerometer FFT')
            xlabel(app.UIAxesAccFFT, 'X')
            ylabel(app.UIAxesAccFFT, 'Y')
            zlabel(app.UIAxesAccFFT, 'Z')
            app.TabGroup2_2 = uitabgroup(app.LabelingTab);
            app.RectpointsFFTTab = uitab(app.TabGroup2_2);
            app.RectpointsFFTTab.Title = 'Rect points FFT';
            app.UIAxesCameraPointsFFT = uiaxes(app.RectpointsFFTTab);
            title(app.UIAxesCameraPointsFFT, 'Rectangle points max FFT from camera')
            xlabel(app.UIAxesCameraPointsFFT, 'X')
            ylabel(app.UIAxesCameraPointsFFT, 'Y')
            zlabel(app.UIAxesCameraPointsFFT, 'Z')
            app.RectsquareFFTTab = uitab(app.TabGroup2_2);
            app.RectsquareFFTTab.Title = 'Rect square FFT';
            app.UIAxesCameraSquareFFT = uiaxes(app.RectsquareFFTTab);
            title(app.UIAxesCameraSquareFFT, 'Rectangle square FFT from camera')
            xlabel(app.UIAxesCameraSquareFFT, 'X')
            ylabel(app.UIAxesCameraSquareFFT, 'Y')
            zlabel(app.UIAxesCameraSquareFFT, 'Z')
            app.AudioFFTTab = uitab(app.TabGroup2_2);
            app.AudioFFTTab.Title = 'Audio FFT';
            app.UIAxesAudioFFT = uiaxes(app.AudioFFTTab);
            title(app.UIAxesAudioFFT, 'Audio FFT')
            xlabel(app.UIAxesAudioFFT, 'X')
            ylabel(app.UIAxesAudioFFT, 'Y')
            zlabel(app.UIAxesAudioFFT, 'Z')
            app.PlayButton = uibutton(app.LabelingTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.LabelingTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.secLabel = uilabel(app.LabelingTab);
            app.secLabel.Text = '0/0 sec';
            app.UIAxes = uiaxes(app.LabelingTab);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, ' ')
            zlabel(app.UIAxes, 'Z')
            app.UIAxesVideo = uiaxes(app.LabelingTab);
            title(app.UIAxesVideo, 'Video from camera')
            zlabel(app.UIAxesVideo, 'Z')
            app.SavefileButton = uibutton(app.PHTLabelAppUIFigure, 'push');
            app.SavefileButton.ButtonPushedFcn = createCallbackFcn(app, @SavefileButtonPushed, true);
            app.SavefileButton.Text = 'Save file';
            app.SelectedmeasurementLabel = uilabel(app.PHTLabelAppUIFigure);
            app.SelectedmeasurementLabel.Text = 'Selected measurement: ';
            app.emptyLabel = uilabel(app.PHTLabelAppUIFigure);
            app.emptyLabel.Text = 'empty';
            app.Message = uilabel(app.PHTLabelAppUIFigure);
            app.Message.Text = ' ';
            app.UpdateallfilesButton = uibutton(app.PHTLabelAppUIFigure, 'push');
            app.UpdateallfilesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateallfilesButtonPushed, true);
            app.UpdateallfilesButton.Text = 'Update all files';
            % Show the figure after all components are created
            app.PHTLabelAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tremorLabeling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PHTLabelAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PHTLabelAppUIFigure)
        end
    end
end